classdef appv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ciezkadoplikuzdanymiLabel       matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        FunkcjafiltrujcaButtonGroup     matlab.ui.container.ButtonGroup
        filterButton                    matlab.ui.control.RadioButton
        filtfiltButton                  matlab.ui.control.RadioButton
        ZmiezakresButton                matlab.ui.control.Button
        FiltrujButton                   matlab.ui.control.Button
        fpEditField                     matlab.ui.control.NumericEditField
        fpEditFieldLabel                matlab.ui.control.Label
        tkEditField                     matlab.ui.control.NumericEditField
        tkEditFieldLabel                matlab.ui.control.Label
        t0EditField                     matlab.ui.control.NumericEditField
        t0EditFieldLabel                matlab.ui.control.Label
        CzasiprbkowanieLabel            matlab.ui.control.Label
        FiltrDropDown                   matlab.ui.control.DropDown
        FiltrDropDownLabel              matlab.ui.control.Label
        FiltracjaLabel                  matlab.ui.control.Label
        WczytajdanezplikuButton         matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        ResetwartociButton              matlab.ui.control.Button
        WywietlhistogramyTCIButton      matlab.ui.control.Button
        TCI_j_per_sTextArea             matlab.ui.control.TextArea
        TCI_j_per_sTextAreaLabel        matlab.ui.control.Label
        GrnagranicahistogramuEditField  matlab.ui.control.NumericEditField
        MaxhistogramedgeLabel           matlab.ui.control.Label
        KrokhistogramuEditField         matlab.ui.control.NumericEditField
        KrokhistogramuEditFieldLabel    matlab.ui.control.Label
        TCI_jTextAreaLabel_2            matlab.ui.control.Label
        WybranyfiltrTextArea            matlab.ui.control.TextArea
        WybranyfiltrTextAreaLabel       matlab.ui.control.Label
        OryginalnefpTextArea            matlab.ui.control.TextArea
        OryginalnefpTextAreaLabel       matlab.ui.control.Label
        CaysygnadoredniejButtonGroup    matlab.ui.container.ButtonGroup
        NIEButton                       matlab.ui.control.RadioButton
        TAKButton                       matlab.ui.control.RadioButton
        ZapiszwykresKierunekYButton     matlab.ui.control.Button
        ZapiszwykresKierunekXButton     matlab.ui.control.Button
        ZapiszButton                    matlab.ui.control.Button
        FormatplikuDropDown             matlab.ui.control.DropDown
        FormatplikuDropDownLabel        matlab.ui.control.Label
        MatlabTextArea                  matlab.ui.control.TextArea
        MatlabTextAreaLabel             matlab.ui.control.Label
        WersjaTextArea                  matlab.ui.control.TextArea
        WersjaTextAreaLabel             matlab.ui.control.Label
        TCI_jTextArea_3                 matlab.ui.control.TextArea
        TCI_jTextArea_3Label            matlab.ui.control.Label
        TCI_jTextArea_2                 matlab.ui.control.TextArea
        TCI_jTextArea_2Label            matlab.ui.control.Label
        TCI_jTextArea                   matlab.ui.control.TextArea
        TCI_jTextAreaLabel              matlab.ui.control.Label
        std_TCI_dT_sTextArea            matlab.ui.control.TextArea
        std_TCI_dT_sTextAreaLabel       matlab.ui.control.Label
        std_TCI_dS_mmTextArea_3         matlab.ui.control.TextArea
        std_TCI_dS_mmTextArea_3Label    matlab.ui.control.Label
        std_TCI_dV_mm_sTextArea         matlab.ui.control.TextArea
        std_TCI_dV_mm_sTextAreaLabel    matlab.ui.control.Label
        TCI_dT_sTextArea_3              matlab.ui.control.TextArea
        TCI_dT_sTextArea_3Label         matlab.ui.control.Label
        TCI_dS_mmTextArea               matlab.ui.control.TextArea
        TCI_dS_mmTextAreaLabel          matlab.ui.control.Label
        TCI_dV_mm_sEditField_3          matlab.ui.control.TextArea
        XLabel                          matlab.ui.control.Label
        TCI_dV_mm_sEditFieldLabel_3     matlab.ui.control.Label
        std_TCI_dT_sTextArea_2          matlab.ui.control.TextArea
        std_TCI_dT_sTextArea_2Label     matlab.ui.control.Label
        std_TCI_dS_mmTextArea_2         matlab.ui.control.TextArea
        std_TCI_dS_mmTextArea_2Label    matlab.ui.control.Label
        std_TCI_dV_mm_sTextArea_2       matlab.ui.control.TextArea
        std_TCI_dV_mm_sTextArea_2Label  matlab.ui.control.Label
        TCI_dT_sTextArea_2              matlab.ui.control.TextArea
        TCI_dT_sTextArea_2Label         matlab.ui.control.Label
        TCI_dS_mmTextArea_2             matlab.ui.control.TextArea
        TCI_dS_mmTextArea_2Label        matlab.ui.control.Label
        TCI_dV_mm_sEditField_2          matlab.ui.control.TextArea
        YLabel                          matlab.ui.control.Label
        TCI_dV_mm_sEditFieldLabel_2     matlab.ui.control.Label
        WYPADKOWELabel                  matlab.ui.control.Label
        std_TCI_dT_sTextArea_3          matlab.ui.control.TextArea
        std_TCI_dT_sTextArea_3Label     matlab.ui.control.Label
        std_TCI_dS_mmTextArea           matlab.ui.control.TextArea
        std_TCI_dS_mmTextAreaLabel      matlab.ui.control.Label
        std_TCI_dV_mm_sTextArea_3       matlab.ui.control.TextArea
        std_TCI_dV_mm_sTextArea_3Label  matlab.ui.control.Label
        TCI_dT_sTextArea                matlab.ui.control.TextArea
        TCI_dT_sTextAreaLabel           matlab.ui.control.Label
        TCI_dS_mmTextArea_3             matlab.ui.control.TextArea
        TCI_dS_mmTextArea_3Label        matlab.ui.control.Label
        TCI_dV_mm_sEditField            matlab.ui.control.TextArea
        TCI_dV_mm_sEditFieldLabel       matlab.ui.control.Label
        ObliczistotnezmianyzapomocrednichkroczacychButton  matlab.ui.control.Button
        DugotrzeciejredniejSpinner      matlab.ui.control.Spinner
        DugotrzeciejredniejSpinnerLabel  matlab.ui.control.Label
        DugodrugiejredniejSpinner       matlab.ui.control.Spinner
        DugodrugiejredniejSpinnerLabel  matlab.ui.control.Label
        DugopierwszejredniejSpinner     matlab.ui.control.Spinner
        DugopierwszejredniejSpinnerLabel  matlab.ui.control.Label
        Slider3                         matlab.ui.control.Slider
        Slider_2Label                   matlab.ui.control.Label
        Slider2                         matlab.ui.control.Slider
        Slider_3Label                   matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        T % aktualnie wyswietlany wektor na osi x
        X % aktualnie wyswietlany wektor na osi Y UIAxes
        Y % aktualnie wyswietlany wektor na osi Y UIAxes2
        Fp % aktualna czestotlwiosc próbkowania
        X_filter % wektor COP w Y po filtracji
        Y_filter % wektor COP w Y po filtracji
        T_filter % wektor czasu  po filtracji
        orig_Fp % originalna czętotliwośc próbkowania
        orig_X % oryginalny wektor X przesunięć COP
        orig_Y % oryginalny wektor Y przesunięć COP
        orig_T % oryginalny wektor czasu
        T0 % aktualnie wyświetlany poczatek badania
        Tk % aktualnie wyświetlany koniec badania
        X_range % wektor przesunięć COP w kierunku X wycięty
        Y_range % wektor przesunięć COP w kierunku Y wycięty
        T_range % wektor czasu wycięty
        status_filtracji % flaga na to czy sygnał jest przefiltrowany czy nie
        T_sampl % wektor czasu po przeprobkowaniu
        X_sampl % wektor przesunięć COP w kierunku X po przeprobkowaniu
        Y_sampl % wektor przesunięć COP w kierunku Y po przeprobkowaniu
        result % struktura z wynikami z PW_MACD6()
        hist_okno % obiekt okna z widokiem histogramów
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function PlikzdanymiEditFieldValueChanged(app, event)

            
        end

        % Button pushed function: WczytajdanezplikuButton
        function WczytajdanezplikuButtonPushed(app, event)
            [filename,path]=uigetfile('*','Wybierz plik');
            figure(app.UIFigure);
            if filename ~= 0 
                a={append(path,filename)};
                app.TextArea.Value=a;
                [app.orig_X, app.orig_Y, app.orig_T, app.orig_Fp]=wczytanie_danych(path, filename);
                app.Fp=app.orig_Fp;
                app.T=app.orig_T;
                app.Slider.Limits=[1, 5999];
                app.Slider2.Limits=[1, 5999];
                app.Slider3.Limits=[1, 5999];
                app.DugopierwszejredniejSpinner.Limits=[1, 5999];
                app.DugodrugiejredniejSpinner.Limits=[1, 5999];
                app.DugotrzeciejredniejSpinner.Limits=[1, 5999];
    
                app.X=app.orig_X;
                app.Y=app.orig_Y;
                app.t0EditField.Value=app.T(1);
                app.T0 = app.t0EditField.Value;
                app.tkEditField.Value=app.T(end);
                app.Tk = app.tkEditField.Value;
                % Dodanie zmiennej status_filtracji na początku programu
                app.status_filtracji = 'brak';
                app.OryginalnefpTextArea.Value = num2str(app.Fp);
                app.fpEditField.Value=app.Fp;
                plot(app.UIAxes,app.T,app.X);
                xlim(app.UIAxes,[app.T(1) app.T(end)])
                plot(app.UIAxes2,app.T,app.Y);
                xlim(app.UIAxes2,[app.T(1) app.T(end)])
    
            end
        end

        % Button pushed function: FiltrujButton
        function FiltrujButtonPushed(app, event)
            if ~isempty(app.FiltrDropDown.Value)
                switch app.FunkcjafiltrujcaButtonGroup.SelectedObject
                    case app.filtfiltButton
                        if app.FiltrDropDown.Value =="test"
                            [app.T_filter,app.X_filter,app.Y_filter]=filtracja_danych(app.FiltrDropDown.Value,app.T,app.X,app.Y);
                        else
                            load(app.FiltrDropDown.Value,"Num","Den")
                            if exist("Num","var") && exist("Den","var")
                                app.T_filter=app.T;
                                app.X_filter=filtfilt(Num,Den,app.X')';
                                app.Y_filter=filtfilt(Num,Den,app.Y')';
                            else
                                app.t_filter=app.T;
                                app.x_filter=app.X;
                                app.y_filter=app.Y;
                            end
                        end
                    case app.filterButton
                        if app.FiltrDropDown.Value =="test"
                            [app.T_filter,app.X_filter,app.Y_filter]=filtracja_danych(app.FiltrDropDown.Value+"f",app.T,app.X,app.Y);
                        else
                            load(app.FiltrDropDown.Value,"Num","Den")
                            if exist("Num","var") && exist("Den","var")
                                app.T_filter=app.T;
                                app.X_filter=filter(Num,Den,app.X')';
                                app.Y_filter=filter(Num,Den,app.Y')';
                            else
                                app.T_filter=app.T;
                                app.X_filter=app.X;
                                app.Y_filter=app.Y;
                            end
                        end
                end
                app.T=app.T_filter;
                app.X=app.X_filter;
                app.Y=app.Y_filter;

                app.WybranyfiltrTextArea.Value = app.FiltrDropDown.Value;
                app.status_filtracji = app.FiltrDropDown.Value; % Zmiana statusu filtracji
                % Wyświetlenie wyników
                plot(app.UIAxes,app.T , app.X);
                plot(app.UIAxes2, app.T, app.Y);
            else
                warndlg('Nie wybrano żadnego filtra','Warning');
            end

        end

        % Button pushed function: ZmiezakresButton
        function ZmiezakresButtonPushed(app, event)

            if app.fpEditField.Value ~= app.Fp
                % przeprobkowanie
                [p,q] = rat(app.fpEditField.Value / app.Fp);
                app.X_sampl = resample(app.X_filter,p,q);
                app.Y_sampl = resample(app.Y_filter,p,q);
                app.Fp=app.fpEditField.Value;
                app.T_sampl = 0:(1/app.Fp):(size(app.X_sampl,2)-1)*(1/app.Fp);
                app.T=app.T_sampl;
                app.X=app.X_sampl;
                app.Y=app.Y_sampl;
                if (app.T0 ~= app.t0EditField.Value || app.Tk ~= app.tkEditField.Value)
                    newT0 = interp1(app.T_sampl, app.T_sampl, app.t0EditField.Value,'nearest');
                    t0_id = find(app.T_sampl == newT0);
                    newTk = interp1(app.T_sampl, app.T_sampl, app.tkEditField.Value,'nearest');
                    tk_id = find(app.T_sampl == newTk);
                    app.T_range = app.T_sampl(t0_id:tk_id);
                    app.X_range = app.X_sampl(t0_id:tk_id);
                    app.Y_range = app.T_sampl(t0_id:tk_id);
                    app.T=app.T_range;
                    app.X=app.X_range;
                    app.Y=app.Y_range;
                end
            else
                % bez przeprobkowania
                newT0 = interp1(app.T_filter, app.T_filter, app.t0EditField.Value,'nearest');
                t0_id = find(app.T_filter == newT0);
                newTk = interp1(app.T_filter, app.T_filter, app.tkEditField.Value,'nearest');
                tk_id = find(app.T_filter == newTk);
                app.T_range = app.T_filter(t0_id:tk_id);
                app.X_range = app.X_filter(t0_id:tk_id);
                app.Y_range = app.Y_filter(t0_id:tk_id);
                app.T=app.T_range;
                app.X=app.X_range;
                app.Y=app.Y_range;
            end

            plot(app.UIAxes,app.T , app.X);
            plot(app.UIAxes2, app.T, app.Y);
        end

        % Value changed function: DugopierwszejredniejSpinner
        function DugopierwszejredniejSpinnerValueChanged(app, event)
            value = app.DugopierwszejredniejSpinner.Value;
            app.Slider.Value= value;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.DugopierwszejredniejSpinner.Value=value;
        end

        % Value changed function: DugodrugiejredniejSpinner
        function DugodrugiejredniejSpinnerValueChanged(app, event)
            value = app.DugodrugiejredniejSpinner.Value;
            app.Slider2.Value=value;
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            app.DugodrugiejredniejSpinner.Value=value;
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            value = app.Slider3.Value;
            app.DugotrzeciejredniejSpinner.Value=value;
        end

        % Value changed function: DugotrzeciejredniejSpinner
        function DugotrzeciejredniejSpinnerValueChanged(app, event)
            value = app.DugotrzeciejredniejSpinner.Value;
            app.Slider3.Value=value;
        end

        % Button pushed function: 
        % ObliczistotnezmianyzapomocrednichkroczacychButton
        function ObliczistotnezmianyzapomocrednichkroczacychButtonPushed(app, event)
            close all;
            if ~isempty(app.X)
                cla(app.UIAxes)
                cla(app.UIAxes2)
                selectedButton2 = app.CaysygnadoredniejButtonGroup.SelectedObject;
                switch selectedButton2
                        case app.TAKButton
                            if (isempty(app.X_sampl))
                                app.result = PW_MACD6(app.T_filter', app.Y_filter', app.X_filter', "wynik", 0, "", 0,...
                                    app.DugopierwszejredniejSpinner.Value,app.DugodrugiejredniejSpinner.Value,app.DugotrzeciejredniejSpinner.Value,...
                                    app.KrokhistogramuEditField.Value, app.GrnagranicahistogramuEditField.Value);
                            else
                                app.result = PW_MACD6(app.T_sampl', app.Y_sampl', app.X_sampl', "wynik", 0, "", 0,...
                                    app.DugopierwszejredniejSpinner.Value,app.DugodrugiejredniejSpinner.Value,app.DugotrzeciejredniejSpinner.Value,...
                                    app.KrokhistogramuEditField.Value, app.GrnagranicahistogramuEditField.Value);
                            end
    
                        case app.NIEButton          
                            app.result = PW_MACD6(app.T_range', app.Y_range', app.X_range', "wynik", 0, "", 0,...
                                app.DugopierwszejredniejSpinner.Value,app.DugodrugiejredniejSpinner.Value,app.DugotrzeciejredniejSpinner.Value,...
                                app.KrokhistogramuEditField.Value, app.GrnagranicahistogramuEditField.Value);
                end
                plot(app.UIAxes,app.result.ML.time,app.result.ML.signal)
                hold(app.UIAxes,'on');
                plot(app.UIAxes,app.result.ML.time(app.result.ML.w),app.result.ML.signal(app.result.ML.w),'o')
                hold(app.UIAxes,'off');
    
                plot(app.UIAxes2,app.result.AP.time,app.result.AP.signal)
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,app.result.AP.time(app.result.AP.w),app.result.AP.signal(app.result.AP.w),'o')
                hold(app.UIAxes2,'off');

                app.std_TCI_dT_sTextArea_3.Value=string(app.result.resultant.std_TCI_dT_s);
                app.std_TCI_dS_mmTextArea.Value=string(app.result.resultant.std_TCI_dS_mm);
                app.std_TCI_dV_mm_sTextArea_3.Value=string(app.result.resultant.std_TCI_dV_mm_s);
                app.TCI_dT_sTextArea.Value=string(app.result.resultant.TCI_dT_s);
                app.TCI_dS_mmTextArea_3.Value=string(app.result.resultant.TCI_dS_mm); 
                app.TCI_dV_mm_sEditField.Value=string(app.result.resultant.TCI_dV_mm_s);
                app.TCI_jTextArea.Value=string(app.result.resultant.TCI_j);
                app.std_TCI_dT_sTextArea_2.Value=string(app.result.AP.std_TCI_dT_s);
                app.std_TCI_dS_mmTextArea_2.Value=string(app.result.AP.std_TCI_dS_mm);
                app.std_TCI_dV_mm_sTextArea_2.Value=string(app.result.AP.std_TCI_dV_mm_s);
                app.TCI_dT_sTextArea_2.Value=string(app.result.AP.TCI_dT_s);
                app.TCI_dS_mmTextArea_2.Value=string(app.result.AP.TCI_dS_mm); 
                app.TCI_dV_mm_sEditField_2.Value=string(app.result.AP.TCI_dV_mm_s);
                app.TCI_jTextArea_2.Value=string(app.result.AP.TCI_j);
                app.std_TCI_dT_sTextArea.Value=string(app.result.ML.std_TCI_dT_s);
                app.std_TCI_dS_mmTextArea_3.Value=string(app.result.ML.std_TCI_dS_mm);
                app.std_TCI_dV_mm_sTextArea.Value=string(app.result.ML.std_TCI_dV_mm_s);
                app.TCI_dT_sTextArea_3.Value=string(app.result.ML.TCI_dT_s);
                app.TCI_dS_mmTextArea.Value=string(app.result.ML.TCI_dS_mm); 
                app.TCI_dV_mm_sEditField_3.Value=string(app.result.ML.TCI_dV_mm_s);
                app.TCI_jTextArea_3.Value=string(app.result.ML.TCI_j);
                app.TCI_j_per_sTextArea.Value=string(app.result.ML.TCI_j_per_s);
                app.WersjaTextArea.Value=string(app.result.info.version);
                app.MatlabTextArea.Value=string(app.result.info.matlab);
            else
                warndlg('Nie wybrano danych do obliczenia MACD!!','Warning');
            end
 
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
           if ~isempty(app.result)
               value = app.FormatplikuDropDown.Value;
               dane.X.X_zapis = app.X;
               dane.Y.Y_zapis = app.Y;
               dane.T.T_zapis = app.T;
               dane.X.X_zapis_range = app.X_range;
               dane.Y.Y_zapis_range = app.Y_range;
               dane.T.T_zapis_range = app.T_range;
               dane.X.std_TCI_dT_X_zapis = str2double(app.std_TCI_dT_sTextArea_3.Value);
               dane.Wypadkowe.std_TCI_dS_Wypadkowy_zapis = str2double(app.std_TCI_dS_mmTextArea.Value);
               dane.X.std_TCI_dV_mm_X_zapis = str2double(app.std_TCI_dV_mm_sTextArea_3.Value);
               dane.Wypadkowe.TCI_dT_Wypadkowy_zapis = str2double(app.TCI_dT_sTextArea.Value);
               dane.X.TCI_dS_X_zapis = str2double(app.TCI_dS_mmTextArea_3.Value); 
               dane.Wypadkowe.TCI_dV_mm_Wypadkowy_zapis = str2double(app.TCI_dV_mm_sEditField.Value);
               dane.Wypadkowe.TCI_j_Wypadkowy_zapis = str2double(app.TCI_jTextArea.Value);
               dane.Y.std_TCI_dT_Y_zapis = str2double(app.std_TCI_dT_sTextArea_2.Value);
               dane.Y.std_TCI_dS_Y_zapis = str2double(app.std_TCI_dS_mmTextArea_2.Value);
               dane.Y.std_TCI_dV_mm_Y_zapis = str2double(app.std_TCI_dV_mm_sTextArea_2.Value);
               dane.Y.TCI_dT_Y_zapis = str2double(app.TCI_dT_sTextArea_2.Value);
               dane.Y.TCI_dS_Y_zapis = str2double(app.TCI_dS_mmTextArea_2.Value); 
               dane.Y.TCI_dV_mm_Y_zapis = str2double(app.TCI_dV_mm_sEditField_2.Value);
               dane.Y.TCI_j_Y_zapis = str2double(app.TCI_jTextArea_2.Value);
               dane.Wypadkowe.std_TCI_dT_Wypadkowy_zapis = str2double(app.std_TCI_dT_sTextArea.Value);
               dane.X.std_TCI_dS_X_zapis = str2double(app.std_TCI_dS_mmTextArea_3.Value);
               dane.Wypadkowe.std_TCI_dV_mm_Wypadkowy_zapis = str2double(app.std_TCI_dV_mm_sTextArea.Value);
               dane.X.TCI_dT_X_zapis = str2double(app.TCI_dT_sTextArea_3.Value);
               dane.Wypadkowe.TCI_dS_Wypadkowy_zapis = str2double(app.TCI_dS_mmTextArea.Value); 
               dane.X.TCI_dV_mm_X_zapis = str2double(app.TCI_dV_mm_sEditField_3.Value);
               dane.X.TCI_j_X_zapis = str2double(app.TCI_jTextArea_3.Value);
               dane.X.X_filter_zapis = app.X_filter;
               dane.Y.Y_filter_zapis = app.Y_filter;
               dane.T.T_filter_zapis = app.T_filter;
               dane.X.X_sampl_zapis = app.X_sampl;
               dane.Y.Y_sampl_zapis = app.Y_sampl;
               dane.T.T_sampl_zapis = app.T_sampl;
               dane.Fp.Oryginalne_fp = str2double(app.OryginalnefpTextArea.Value);
               dane.Fp.Aktualne_fp=app.Fp;
               dane.Filtr.Wybrany_filtr = app.WybranyfiltrTextArea.Value;
               dane.Dane.Plik_z_badaniem = app.TextArea.Value;
               dane.X.X_t_hist_zapis = app.result.ML.t_hist;
               dane.X.X_histogram_zapis =  app.result.ML.histcountsN; %app.result.ML.histogram;
               dane.Y.Y_t_hist_zapis = app.result.AP.t_hist;
               dane.Y.Y_histogram_zapis =  app.result.AP.histcountsN; %app.result.AP.histogram;
               dane.Wypadkowe.Wypadkowy_t_hist_zapis = app.result.resultant.t_hist;
               dane.Wypadkowe.Wypadkowy_histogram_zapis = app.result.resultant.histcounts; %app.result.resultant.histogram;
               dane.Wypadkowe.TCI_j_per_s_Wypadkowy_zapis=app.result.resultant.TCI_j_per_s;
               
                 switch value
                     case 'mat'
                       [file,path] = uiputfile('*.mat','Zapisz jako plik .mat'); 
                       %save(fullfile(path,file),'oryginalne_fp','wybrany_filtr','X_zapis', 'Y_zapis', 'T_zapis','X_zapis_range', 'Y_zapis_range', 'T_zapis_range','std_TCI_dT_X_zapis', 'std_TCI_dS_Wypadkowy_zapis','std_TCI_dV_mm_X_zapis','TCI_dT_Wypadkowy_zapis','TCI_dS_X_zapis', 'TCI_dV_mm_Wypadkowy_zapis','TCI_j_Wypadkowy_zapis','std_TCI_dT_Y_zapis','std_TCI_dS_Y_zapis','std_TCI_dV_mm_Y_zapis','TCI_dT_Y_zapis','TCI_dS_Y_zapis','TCI_dV_mm_Y_zapis','TCI_j_Y_zapis','std_TCI_dT_Wypadkowy_zapis','std_TCI_dS_X_zapis','std_TCI_dV_mm_Wypadkowy_zapis','TCI_dT_X_zapis','TCI_dS_Wypadkowy_zapis','TCI_dV_mm_X_zapis','TCI_j_X_zapis', 'X_filter_zapis', 'Y_filter_zapis', 'T_filter_zapis')
                        if file==0
                          return
                        end
                       save(fullfile(path,file),'dane');
                    case 'json'
                        %data = struct('oryginalne_fp', oryginalne_fp, 'wybrany_filtr', wybrany_filtr,'X_zapis',X_zapis, 'Y_zapis',Y_zapis, 'T_zapis', T_zapis,'X_zapis_range', X_zapis_range', 'Y_zapis_range', Y_zapis_range, 'T_zapis_range',T_zapis_range,'std_TCI_dT_X_zapis',std_TCI_dT_X_zapis, 'std_TCI_dS_Wypadkowy_zapis',std_TCI_dS_Wypadkowy_zapis,'std_TCI_dV_mm_X_zapis',std_TCI_dV_mm_X_zapis,'TCI_dT_Wypadkowy_zapis',TCI_dT_Wypadkowy_zapis,'TCI_dS_X_zapis', TCI_dS_X_zapis,'TCI_dV_mm_Wypadkowy_zapis',TCI_dV_mm_Wypadkowy_zapis,'TCI_j_Wypadkowy_zapis',TCI_j_Wypadkowy_zapis,'std_TCI_dT_Y_zapis',std_TCI_dT_Y_zapis,'std_TCI_dS_Y_zapis',std_TCI_dS_Y_zapis,'std_TCI_dV_mm_Y_zapis',std_TCI_dV_mm_Y_zapis,'TCI_dT_Y_zapis',TCI_dT_Y_zapis,'TCI_dS_Y_zapis',TCI_dS_Y_zapis,'TCI_dV_mm_Y_zapis',TCI_dV_mm_Y_zapis,'TCI_j_Y_zapis',TCI_j_Y_zapis,'std_TCI_dT_Wypadkowy_zapis',std_TCI_dT_Wypadkowy_zapis,'std_TCI_dS_X_zapis',std_TCI_dS_X_zapis,'std_TCI_dV_mm_Wypadkowy_zapis',std_TCI_dV_mm_Wypadkowy_zapis,'TCI_dT_X_zapis',TCI_dT_X_zapis,'TCI_dS_Wypadkowy_zapis',TCI_dS_Wypadkowy_zapis,'TCI_dV_mm_X_zapis',TCI_dV_mm_X_zapis,'TCI_j_X_zapis', TCI_j_X_zapis,'X_filter_zapis',X_filter_zapis, 'Y_filter_zapis', Y_filter_zapis,'T_filter_zapis',T_filter_zapis);
                        jsonStr = jsonencode(dane, PrettyPrint=true);
                        [file,path] = uiputfile('*.json','Zapisz jako JSON');
                        if file==0
                          return
                        end
                        filePath = fullfile(path,file);
                        fid = fopen(filePath,'w');
                        fprintf(fid,'%s',jsonStr);
                        fclose(fid);
                     case 'xlsx'
                        [file,path] = uiputfile('*.xlsx','Zapisz jako plik Excel');
                        if file==0
                          return
                        end
                        excelFilename=append(path,file);
                        structFieldnames = fieldnames(dane);
                        t=[];
                        name=[];
                        for i = 1:length(structFieldnames)
                            insideStructName = structFieldnames{i};
                            finalStructFieldnames = fieldnames(dane.(insideStructName));
                            for j = 1:length(finalStructFieldnames)
                                fieldname = finalStructFieldnames{j};
                                if length(dane.(insideStructName).(fieldname))==1
                                    name=[name, fieldname + " "];
                                    t=[t , dane.(insideStructName).(fieldname)];
                                end
                            end
                        end
                        for i=1:length(dane.X.X_histogram_zapis)
                            temp= num2str(dane.X.X_t_hist_zapis(i))+"-"+num2str(dane.X.X_t_hist_zapis(i+1))+"_X_hist";
                            name=[name,temp];
                            t=[t,dane.X.X_histogram_zapis(i)];
                        end
                        for i=1:length(dane.Y.Y_histogram_zapis)
                            temp= num2str(dane.Y.Y_t_hist_zapis(i))+"-"+num2str(dane.Y.Y_t_hist_zapis(i+1))+"_Y_hist";
                            name=[name,temp];
                            t=[t,dane.Y.Y_histogram_zapis(i)];
                        end
                        for i=1:length(dane.Wypadkowe.Wypadkowy_histogram_zapis)
                            temp= num2str(dane.Wypadkowe.Wypadkowy_t_hist_zapis(i))+"-"+num2str(dane.Wypadkowe.Wypadkowy_t_hist_zapis(i+1))+"_Wypadkowy_hist";
                            name=[name,temp];
                            t=[t,dane.Wypadkowe.Wypadkowy_histogram_zapis(i)];
                        end
                        name1=erase(strip(name),"_zapis");
                        if isfile(excelFilename)
                            writecell(t,excelFilename,"WriteMode","append");
                        else
                            writematrix(name1,excelFilename);
                            writecell(t,excelFilename,"WriteMode","append");
                        end
                        msgbox("Pomyślnie zapisano wyniki!","Udany zapis","help");
                 end
           else
                warndlg('Nie obliczono jeszcze MACD oraz TCI!!!','Warning');
           end
        end

        % Button pushed function: ZapiszwykresKierunekXButton
        function ZapiszwykresKierunekXButtonPushed(app, event)
                                    % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.XLabel.String='t';
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.YLabel.String='X';
            figAxes.ZLim = app.UIAxes.ZLim;
            figAxes.Title.String='Dane X';
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            % Save as png and fig files.
            [file,path] = uiputfile('*.png');
            %save(fullfile(path,file),"fig")
            if file==0
              return
            end
             saveas(fig, fullfile(path,file));
%             savefig(fig, 'WykresX');
            % Delete the temporary figure.
            delete(fig);
        end

        % Button pushed function: ZapiszwykresKierunekYButton
        function ZapiszwykresKierunekYButtonPushed(app, event)
            fig_2 = figure;
            fig_2.Visible = 'off';
            figAxes_2 = axes(fig_2);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren_2 = app.UIAxes2.XAxis.Parent.Children;
            copyobj(allChildren_2, figAxes_2)
            figAxes_2.XLim = app.UIAxes2.XLim;
            figAxes_2.XLabel.String='t';
            figAxes_2.YLim = app.UIAxes2.YLim;
            figAxes_2.YLabel.String='Y';
            figAxes_2.ZLim = app.UIAxes2.ZLim;
            figAxes_2.Title.String='Dane Y';
            figAxes_2.DataAspectRatio = app.UIAxes2.DataAspectRatio;
            % Save as png and fig files.
            [file,path] = uiputfile('*.png');
            if file==0
              return
            end
            %save(fullfile(path,file),"fig")
             saveas(fig_2, fullfile(path,file));
%             savefig(fig, 'WykresX');
            % Delete the temporary figure.
            delete(fig_2);    
        end

        % Callback function
        function CaysygnadoredniejButtonGroupSelectionChanged(app, event)

            
        end

        % Drop down opening function: FiltrDropDown
        function FiltrDropDownOpening(app, event)
            info=dir("filters");
            files(1,1)="test";
            iter=2;
            for i=1:size(info,1)
                if info(i).isdir ==0
                    disp(info(i).folder+"\"+info(i).name)
                    files(iter,1)=info(i).folder+"\"+info(i).name;%info(i).name;
                    iter=iter+1;
                end
            end
            app.FiltrDropDown.Items=files;
        end

        % Callback function
        function OryginalnefpTextAreaValueChanged(app, event)

            
        end

        % Button pushed function: WywietlhistogramyTCIButton
        function WywietlhistogramyTCIButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            if ~isempty(app.result)
                app.WywietlhistogramyTCIButton.Enable = "off";
                t_hist=0:app.KrokhistogramuEditField.Value:app.GrnagranicahistogramuEditField.Value;
                app.hist_okno = histogram_window(app,t_hist, diff(app.result.ML.time(app.result.ML.w)), diff(app.result.AP.time(app.result.AP.w)), app.result.resultant.histcounts);
            else
                warndlg('Nie wobliczono jeszcze MACD oraz TCI!!!','Warning');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.hist_okno);
            delete(app)
        end

        % Button pushed function: ResetwartociButton
        function ResetwartociButtonPushed(app, event)
            app.DugopierwszejredniejSpinner.Value = 12;
            app.DugodrugiejredniejSpinner.Value = 26;
            app.DugotrzeciejredniejSpinner.Value = 9;
            app.CaysygnadoredniejButtonGroup.SelectedObject = app.TAKButton;
            app.KrokhistogramuEditField.Value = 0.1;
            app.GrnagranicahistogramuEditField.Value = 2;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {664, 664};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WczytajdanezplikuButton = uibutton(app.LeftPanel, 'push');
            app.WczytajdanezplikuButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajdanezplikuButtonPushed, true);
            app.WczytajdanezplikuButton.Text = 'Wczytaj dane z pliku';
            app.FiltracjaLabel = uilabel(app.LeftPanel);
            app.FiltracjaLabel.Text = 'Filtracja';
            app.FiltrDropDownLabel = uilabel(app.LeftPanel);
            app.FiltrDropDownLabel.Text = 'Filtr';
            app.FiltrDropDown = uidropdown(app.LeftPanel);
            app.FiltrDropDown.Items = {};
            app.FiltrDropDown.Editable = 'on';
            app.FiltrDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FiltrDropDownOpening, true);
            app.FiltrDropDown.Placeholder = 'Wybierz filtr';
            app.FiltrDropDown.Value = {};
            app.CzasiprbkowanieLabel = uilabel(app.LeftPanel);
            app.CzasiprbkowanieLabel.Text = 'Czas i próbkowanie';
            app.t0EditFieldLabel = uilabel(app.LeftPanel);
            app.t0EditFieldLabel.Text = 't0';
            app.t0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.tkEditFieldLabel = uilabel(app.LeftPanel);
            app.tkEditFieldLabel.Text = 'tk';
            app.tkEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tkEditField.Limits = [0 Inf];
            app.fpEditFieldLabel = uilabel(app.LeftPanel);
            app.fpEditFieldLabel.Text = 'fp';
            app.fpEditField = uieditfield(app.LeftPanel, 'numeric');
            app.fpEditField.Limits = [0 Inf];
            app.FiltrujButton = uibutton(app.LeftPanel, 'push');
            app.FiltrujButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrujButtonPushed, true);
            app.FiltrujButton.Text = 'Filtruj';
            app.ZmiezakresButton = uibutton(app.LeftPanel, 'push');
            app.ZmiezakresButton.ButtonPushedFcn = createCallbackFcn(app, @ZmiezakresButtonPushed, true);
            app.ZmiezakresButton.Text = 'Zmień zakres';
            app.FunkcjafiltrujcaButtonGroup = uibuttongroup(app.LeftPanel);
            app.FunkcjafiltrujcaButtonGroup.Title = 'Funkcja filtrująca';
            app.filtfiltButton = uiradiobutton(app.FunkcjafiltrujcaButtonGroup);
            app.filtfiltButton.Text = 'filtfilt()';
            app.filtfiltButton.Value = true;
            app.filterButton = uiradiobutton(app.FunkcjafiltrujcaButtonGroup);
            app.filterButton.Text = 'filter()';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.ciezkadoplikuzdanymiLabel = uilabel(app.LeftPanel);
            app.ciezkadoplikuzdanymiLabel.Text = 'Ściezka do pliku z danymi';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Kierunek X')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Kierunek Y')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.Slider = uislider(app.RightPanel);
            app.Slider.Limits = [1 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 12;
            app.Slider_3Label = uilabel(app.RightPanel);
            app.Slider_3Label.Text = '';
            app.Slider2 = uislider(app.RightPanel);
            app.Slider2.Limits = [1 100];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.Value = 26;
            app.Slider_2Label = uilabel(app.RightPanel);
            app.Slider_2Label.Text = '';
            app.Slider3 = uislider(app.RightPanel);
            app.Slider3.Limits = [1 100];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.Value = 9;
            app.DugopierwszejredniejSpinnerLabel = uilabel(app.RightPanel);
            app.DugopierwszejredniejSpinnerLabel.Text = 'Długość pierwszej średniej';
            app.DugopierwszejredniejSpinner = uispinner(app.RightPanel);
            app.DugopierwszejredniejSpinner.Limits = [1 Inf];
            app.DugopierwszejredniejSpinner.RoundFractionalValues = 'on';
            app.DugopierwszejredniejSpinner.ValueDisplayFormat = '%.0f';
            app.DugopierwszejredniejSpinner.ValueChangedFcn = createCallbackFcn(app, @DugopierwszejredniejSpinnerValueChanged, true);
            app.DugopierwszejredniejSpinner.Value = 12;
            app.DugodrugiejredniejSpinnerLabel = uilabel(app.RightPanel);
            app.DugodrugiejredniejSpinnerLabel.Text = 'Długość drugiej średniej';
            app.DugodrugiejredniejSpinner = uispinner(app.RightPanel);
            app.DugodrugiejredniejSpinner.Limits = [1 Inf];
            app.DugodrugiejredniejSpinner.RoundFractionalValues = 'on';
            app.DugodrugiejredniejSpinner.ValueDisplayFormat = '%.0f';
            app.DugodrugiejredniejSpinner.ValueChangedFcn = createCallbackFcn(app, @DugodrugiejredniejSpinnerValueChanged, true);
            app.DugodrugiejredniejSpinner.Value = 26;
            app.DugotrzeciejredniejSpinnerLabel = uilabel(app.RightPanel);
            app.DugotrzeciejredniejSpinnerLabel.Text = 'Długość trzeciej średniej';
            app.DugotrzeciejredniejSpinner = uispinner(app.RightPanel);
            app.DugotrzeciejredniejSpinner.Limits = [1 Inf];
            app.DugotrzeciejredniejSpinner.RoundFractionalValues = 'on';
            app.DugotrzeciejredniejSpinner.ValueDisplayFormat = '%.0f';
            app.DugotrzeciejredniejSpinner.ValueChangedFcn = createCallbackFcn(app, @DugotrzeciejredniejSpinnerValueChanged, true);
            app.DugotrzeciejredniejSpinner.Value = 9;
            app.ObliczistotnezmianyzapomocrednichkroczacychButton = uibutton(app.RightPanel, 'push');
            app.ObliczistotnezmianyzapomocrednichkroczacychButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczistotnezmianyzapomocrednichkroczacychButtonPushed, true);
            app.ObliczistotnezmianyzapomocrednichkroczacychButton.Text = 'Oblicz istotne zmiany za pomocą średnich kroczacych';
            app.TCI_dV_mm_sEditFieldLabel = uilabel(app.RightPanel);
            app.TCI_dV_mm_sEditFieldLabel.Text = 'TCI_dV_mm_s';
            app.TCI_dV_mm_sEditField = uitextarea(app.RightPanel);
            app.TCI_dS_mmTextArea_3Label = uilabel(app.RightPanel);
            app.TCI_dS_mmTextArea_3Label.Text = 'TCI_dS_mm';
            app.TCI_dS_mmTextArea_3 = uitextarea(app.RightPanel);
            app.TCI_dT_sTextAreaLabel = uilabel(app.RightPanel);
            app.TCI_dT_sTextAreaLabel.Text = 'TCI_dT_s';
            app.TCI_dT_sTextArea = uitextarea(app.RightPanel);
            app.std_TCI_dV_mm_sTextArea_3Label = uilabel(app.RightPanel);
            app.std_TCI_dV_mm_sTextArea_3Label.Text = 'std_TCI_dV_mm_s';
            app.std_TCI_dV_mm_sTextArea_3 = uitextarea(app.RightPanel);
            app.std_TCI_dS_mmTextAreaLabel = uilabel(app.RightPanel);
            app.std_TCI_dS_mmTextAreaLabel.Text = 'std_TCI_dS_mm';
            app.std_TCI_dS_mmTextArea = uitextarea(app.RightPanel);
            app.std_TCI_dT_sTextArea_3Label = uilabel(app.RightPanel);
            app.std_TCI_dT_sTextArea_3Label.Text = 'std_TCI_dT_s';
            app.std_TCI_dT_sTextArea_3 = uitextarea(app.RightPanel);
            app.WYPADKOWELabel = uilabel(app.RightPanel);
            app.WYPADKOWELabel.Text = 'WYPADKOWE';
            app.TCI_dV_mm_sEditFieldLabel_2 = uilabel(app.RightPanel);
            app.TCI_dV_mm_sEditFieldLabel_2.Text = 'TCI_dV_mm_s';
            app.YLabel = uilabel(app.RightPanel);
            app.YLabel.Text = 'Y';
            app.TCI_dV_mm_sEditField_2 = uitextarea(app.RightPanel);
            app.TCI_dS_mmTextArea_2Label = uilabel(app.RightPanel);
            app.TCI_dS_mmTextArea_2Label.Text = 'TCI_dS_mm';
            app.TCI_dS_mmTextArea_2 = uitextarea(app.RightPanel);
            app.TCI_dT_sTextArea_2Label = uilabel(app.RightPanel);
            app.TCI_dT_sTextArea_2Label.Text = 'TCI_dT_s';
            app.TCI_dT_sTextArea_2 = uitextarea(app.RightPanel);
            app.std_TCI_dV_mm_sTextArea_2Label = uilabel(app.RightPanel);
            app.std_TCI_dV_mm_sTextArea_2Label.Text = 'std_TCI_dV_mm_s';
            app.std_TCI_dV_mm_sTextArea_2 = uitextarea(app.RightPanel);
            app.std_TCI_dS_mmTextArea_2Label = uilabel(app.RightPanel);
            app.std_TCI_dS_mmTextArea_2Label.Text = 'std_TCI_dS_mm';
            app.std_TCI_dS_mmTextArea_2 = uitextarea(app.RightPanel);
            app.std_TCI_dT_sTextArea_2Label = uilabel(app.RightPanel);
            app.std_TCI_dT_sTextArea_2Label.Text = 'std_TCI_dT_s';
            app.std_TCI_dT_sTextArea_2 = uitextarea(app.RightPanel);
            app.TCI_dV_mm_sEditFieldLabel_3 = uilabel(app.RightPanel);
            app.TCI_dV_mm_sEditFieldLabel_3.Text = 'TCI_dV_mm_s';
            app.XLabel = uilabel(app.RightPanel);
            app.XLabel.Text = 'X';
            app.TCI_dV_mm_sEditField_3 = uitextarea(app.RightPanel);
            app.TCI_dS_mmTextAreaLabel = uilabel(app.RightPanel);
            app.TCI_dS_mmTextAreaLabel.Text = 'TCI_dS_mm';
            app.TCI_dS_mmTextArea = uitextarea(app.RightPanel);
            app.TCI_dT_sTextArea_3Label = uilabel(app.RightPanel);
            app.TCI_dT_sTextArea_3Label.Text = 'TCI_dT_s';
            app.TCI_dT_sTextArea_3 = uitextarea(app.RightPanel);
            app.std_TCI_dV_mm_sTextAreaLabel = uilabel(app.RightPanel);
            app.std_TCI_dV_mm_sTextAreaLabel.Text = 'std_TCI_dV_mm_s';
            app.std_TCI_dV_mm_sTextArea = uitextarea(app.RightPanel);
            app.std_TCI_dS_mmTextArea_3Label = uilabel(app.RightPanel);
            app.std_TCI_dS_mmTextArea_3Label.Text = 'std_TCI_dS_mm';
            app.std_TCI_dS_mmTextArea_3 = uitextarea(app.RightPanel);
            app.std_TCI_dT_sTextAreaLabel = uilabel(app.RightPanel);
            app.std_TCI_dT_sTextAreaLabel.Text = 'std_TCI_dT_s';
            app.std_TCI_dT_sTextArea = uitextarea(app.RightPanel);
            app.TCI_jTextAreaLabel = uilabel(app.RightPanel);
            app.TCI_jTextAreaLabel.Text = 'TCI_j';
            app.TCI_jTextArea = uitextarea(app.RightPanel);
            app.TCI_jTextArea_2Label = uilabel(app.RightPanel);
            app.TCI_jTextArea_2Label.Text = 'TCI_j';
            app.TCI_jTextArea_2 = uitextarea(app.RightPanel);
            app.TCI_jTextArea_3Label = uilabel(app.RightPanel);
            app.TCI_jTextArea_3Label.Text = 'TCI_j';
            app.TCI_jTextArea_3 = uitextarea(app.RightPanel);
            app.WersjaTextAreaLabel = uilabel(app.RightPanel);
            app.WersjaTextAreaLabel.Text = 'Wersja';
            app.WersjaTextArea = uitextarea(app.RightPanel);
            app.MatlabTextAreaLabel = uilabel(app.RightPanel);
            app.MatlabTextAreaLabel.Text = 'Matlab';
            app.MatlabTextArea = uitextarea(app.RightPanel);
            app.FormatplikuDropDownLabel = uilabel(app.RightPanel);
            app.FormatplikuDropDownLabel.Text = 'Format pliku';
            app.FormatplikuDropDown = uidropdown(app.RightPanel);
            app.FormatplikuDropDown.Items = {'xlsx', 'mat', 'json'};
            app.FormatplikuDropDown.Value = 'xlsx';
            app.ZapiszButton = uibutton(app.RightPanel, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Text = 'Zapisz';
            app.ZapiszwykresKierunekXButton = uibutton(app.RightPanel, 'push');
            app.ZapiszwykresKierunekXButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszwykresKierunekXButtonPushed, true);
            app.ZapiszwykresKierunekXButton.Text = 'Zapisz wykres Kierunek X';
            app.ZapiszwykresKierunekYButton = uibutton(app.RightPanel, 'push');
            app.ZapiszwykresKierunekYButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszwykresKierunekYButtonPushed, true);
            app.ZapiszwykresKierunekYButton.Text = 'Zapisz wykres Kierunek Y';
            app.CaysygnadoredniejButtonGroup = uibuttongroup(app.RightPanel);
            app.CaysygnadoredniejButtonGroup.Title = 'Cały sygnał do średniej';
            app.TAKButton = uiradiobutton(app.CaysygnadoredniejButtonGroup);
            app.TAKButton.Text = 'TAK';
            app.TAKButton.Value = true;
            app.NIEButton = uiradiobutton(app.CaysygnadoredniejButtonGroup);
            app.NIEButton.Text = 'NIE';
            app.OryginalnefpTextAreaLabel = uilabel(app.RightPanel);
            app.OryginalnefpTextAreaLabel.Text = 'Oryginalne fp';
            app.OryginalnefpTextArea = uitextarea(app.RightPanel);
            app.WybranyfiltrTextAreaLabel = uilabel(app.RightPanel);
            app.WybranyfiltrTextAreaLabel.Text = 'Wybrany filtr';
            app.WybranyfiltrTextArea = uitextarea(app.RightPanel);
            app.TCI_jTextAreaLabel_2 = uilabel(app.RightPanel);
            app.TCI_jTextAreaLabel_2.Text = 'TCI_j';
            app.KrokhistogramuEditFieldLabel = uilabel(app.RightPanel);
            app.KrokhistogramuEditFieldLabel.Text = 'Krok  histogramu';
            app.KrokhistogramuEditField = uieditfield(app.RightPanel, 'numeric');
            app.KrokhistogramuEditField.Limits = [0.01 Inf];
            app.KrokhistogramuEditField.Value = 0.1;
            app.MaxhistogramedgeLabel = uilabel(app.RightPanel);
            app.MaxhistogramedgeLabel.Text = 'Górna granica histogramu';
            app.GrnagranicahistogramuEditField = uieditfield(app.RightPanel, 'numeric');
            app.GrnagranicahistogramuEditField.Limits = [1 Inf];
            app.GrnagranicahistogramuEditField.Value = 2;
            app.TCI_j_per_sTextAreaLabel = uilabel(app.RightPanel);
            app.TCI_j_per_sTextAreaLabel.Text = 'TCI_j_per_s';
            app.TCI_j_per_sTextArea = uitextarea(app.RightPanel);
            app.WywietlhistogramyTCIButton = uibutton(app.RightPanel, 'push');
            app.WywietlhistogramyTCIButton.ButtonPushedFcn = createCallbackFcn(app, @WywietlhistogramyTCIButtonPushed, true);
            app.WywietlhistogramyTCIButton.Text = 'Wyświetl histogramy TCI';
            app.ResetwartociButton = uibutton(app.RightPanel, 'push');
            app.ResetwartociButton.ButtonPushedFcn = createCallbackFcn(app, @ResetwartociButtonPushed, true);
            app.ResetwartociButton.Text = 'Reset wartości';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end